classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Menu                     matlab.ui.container.Menu
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        TabGroup                 matlab.ui.container.TabGroup
        EfectosTab               matlab.ui.container.Tab
        AadirefectoButton        matlab.ui.control.Button
        Tree                     matlab.ui.container.Tree
        Node                     matlab.ui.container.TreeNode
        Node2                    matlab.ui.container.TreeNode
        Node3                    matlab.ui.container.TreeNode
        Node4                    matlab.ui.container.TreeNode
        Tab2                     matlab.ui.container.Tab
        InfoPanel                matlab.ui.container.Panel
        TextArea                 matlab.ui.control.TextArea
        CargaButton              matlab.ui.control.Button
        GuardarButton            matlab.ui.control.Button
        PlayButton               matlab.ui.control.Button
        PsButton                 matlab.ui.control.Button
        StopButton               matlab.ui.control.Button
        rButton                  matlab.ui.control.StateButton
        GenSenalesPanel          matlab.ui.container.Panel
        TipodeondaDropDownLabel  matlab.ui.control.Label
        TipoOnda                 matlab.ui.control.DropDown
        AmplitudLabel            matlab.ui.control.Label
        AmplitEditField          matlab.ui.control.EditField
        VLabel                   matlab.ui.control.Label
        HzLabel                  matlab.ui.control.Label
        FrecEditField            matlab.ui.control.EditField
        FrecuenciaLabel          matlab.ui.control.Label
        sLabel                   matlab.ui.control.Label
        DurationEditField        matlab.ui.control.EditField
        DuracionLabel            matlab.ui.control.Label
        GenerarButton            matlab.ui.control.Button
        HzLabel_2                matlab.ui.control.Label
        FrecmuestreoEditField    matlab.ui.control.EditField
        FrecmuestreoLabel        matlab.ui.control.Label
    end

    
    properties (Access = public)
        last_data; %Datos de ultima modificación se escribira siempre que se aplique un efecto o se grabe algo por micro
        
        data; %Datos crudos de audio de lectura del fichero
        fs_lectura;
        info; %Info del fichero de audio
        
        recorder=audiorecorder(16000,16,2); %Objeto de grabacion por micro
        grabacion; %Datos crudos de grabacion por micrófono
        
        audio; %Objeto de audio para reproduccion
        
        
    end
    
    methods (Access = private)
        
        function mostrarInfoLectura(app)
            aux=split(string(app.info.Filename),'\');
            app.TextArea.Value=sprintf( ...
                'Fichero: %s\n\nDuración: %.2f s\n\nTasa de muestreo: %.2f Hz\n\nMuestras totales: %.0f\n', ...
                aux(end), ...
                string(app.info.Duration), ...
                string(app.info.SampleRate), ...
                string(app.info.TotalSamples));
        end
        
        function mostrarInfoGrabacion(app)
            app.TextArea.Value=sprintf( ...
                'Duración: %.2f s\n\nTasa de muestreo: %.2f Hz\n\nMuestras totales: %.0f\n', ...
                string((app.recorder.TotalSamples-1)*(1/app.recorder.SampleRate)), ...
                string(app.recorder.SampleRate), ...
                string(app.recorder.TotalSamples));
        end
        
        function dibPlotTiempos(app,base,datos)
            reset(app.UIAxes); %Limpamos grafica antes de dibujar
            plot(app.UIAxes,base,datos);
            xlabel(app.UIAxes,'Tiempo [s]');
            ylabel(app.UIAxes,'Amplitud [V]');
        end  
        
        function dibPlotFrecuencias(app,f,Y)
            reset(app.UIAxes2); %Limpamos grafica antes de dibujar
            plot(app.UIAxes2,f,Y);
            xlabel(app.UIAxes2,'Frecuencia [Hz]');
            ylabel(app.UIAxes2,'Amplitud [V]');
        end  
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargaButton
        function CargaButtonPushed(app, event)
            [file,path]=uigetfile({'*.wav;*.flac;*.ogg;*.mp3;*.mp4;*.m4a'...
                ,'Extensiones Audio (*.wav;*.flac;*.ogg;*.mp3;*.mp4;*.m4a)'},'Seleccione el archivo de audio'); 
            if isequal(file,0)
            return
            end
            [app.data, app.fs_lectura]=audioread(fullfile(path,file));      %fullfile concatena el path y el archivo para poder leerlo desde donde sea
            app.info=audioinfo(fullfile(path,file));

            app.audio=audioplayer(app.data,app.fs_lectura);
            
            mostrarInfoLectura(app);%función para mostrar informacion
            
            t=0:seconds(1/app.audio.SampleRate):seconds((1/app.audio.SampleRate)*(app.audio.TotalSamples-1));
            
            dibPlotTiempos(app,t,app.data);
            
            app.last_data=app.data;
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            if (size(app.audio)~=0) 
                if strcmp(app.audio.Running,'off')%if anidado porque no sabemos si puedo usar strmp hasta que me aseguro que audio no esta vacio
                    if app.audio.CurrentSample==0
                        play(app.audio);
                    else
                        resume(app.audio);
                    end
                end
            end
        end

        % Value changed function: rButton
        function rButtonValueChanged(app, event)
            value = app.rButton.Value;
            
            if value==1
                record(app.recorder);
            elseif value==0
                stop(app.recorder);
                app.grabacion=getaudiodata(app.recorder);
                
                t=0:seconds(1/app.recorder.SampleRate):seconds((app.recorder.TotalSamples-1)*(1/app.recorder.SampleRate));
                
                plot(app.UIAxes,t,app.grabacion);
                
                mostrarInfoGrabacion(app);
                
                app.audio=audioplayer(app.recorder);
                app.last_data=app.grabacion; 
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
          if size(app.audio)~=0
              stop(app.audio);
          end
        end

        % Button pushed function: PsButton
        function PsButtonPushed(app, event)
          if size(app.audio)~=0
            if strcmp(app.audio.Running,'on')
                  pause(app.audio);
            end
          end
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            if size(app.last_data)~=0
                [file,path] = uiputfile({'*.wav';'*.mp4';'*.ogg';'*.flac';'*.m4a'},'Guarda la grabación','grabacion.wav'); 
                audiowrite(fullfile(path,file),app.last_data,16000);%Hay que cambiar ese 16000 por algo que pueda elegir el usuario
            end
        end

        % Size changed function: GenSenalesPanel
        function GenSenalesPanelSizeChanged(app, event)
           
            
        end

        % Button pushed function: GenerarButton
        function GenerarButtonPushed(app, event)
 % Generador de funciones elementales
            
            % Parámetros introducidos por pantalla
            opcion=app.TipoOnda.Value;
            amplitud=str2double(app.AmplitEditField.Value);
            frecuencia=str2double(app.FrecEditField.Value);
            duracion=str2double(app.DurationEditField.Value);
            fs=str2double(app.FrecmuestreoEditField.Value);
            numMuestras=fs*duracion;
            
            % Vector de tiempos 
            t= linspace(0, duracion, numMuestras);
            %Vector de frecuencias
            f = linspace(-fs/2, fs/2, numMuestras);
            
            switch opcion
                case 'Seno'
                    y=amplitud * sin(2*pi*frecuencia * t);
                case 'Coseno'
                    y=amplitud * cos(2*pi*frecuencia * t);
                case 'Cuadrada'
                    y=amplitud * square(frecuencia * t);
                case 'Triangular'
                    y = amplitud * sawtooth(frecuencia * t, 0.5);
                case 'Sierra'
                    y = amplitud * sawtooth(frecuencia * t);    
            end
            
            Y = (1/length(y)) * fftshift(fft(y));
            
            dibPlotTiempos(app,t,y);
            dibPlotFrecuencias(app,f,Y)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Tiempo [s]')
            ylabel(app.UIAxes, 'Amplitud [V]')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'Frecuencia [Hz]')
            ylabel(app.UIAxes2, 'Amplitud [V]')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EfectosTab = uitab(app.TabGroup);
            app.EfectosTab.Title = 'Efectos';
            app.AadirefectoButton = uibutton(app.EfectosTab, 'push');
            app.AadirefectoButton.Text = 'Añadir efecto';
            app.Tree = uitree(app.EfectosTab);
            app.Node = uitreenode(app.Tree);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Node);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.InfoPanel = uipanel(app.UIFigure);
            app.InfoPanel.Title = 'Info';
            app.TextArea = uitextarea(app.InfoPanel);
            app.CargaButton = uibutton(app.UIFigure, 'push');
            app.CargaButton.ButtonPushedFcn = createCallbackFcn(app, @CargaButtonPushed, true);
            app.CargaButton.Text = 'Carga';
            app.GuardarButton = uibutton(app.UIFigure, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PsButton = uibutton(app.UIFigure, 'push');
            app.PsButton.ButtonPushedFcn = createCallbackFcn(app, @PsButtonPushed, true);
            app.PsButton.Text = 'Ps';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'S';
            app.rButton = uibutton(app.UIFigure, 'state');
            app.rButton.ValueChangedFcn = createCallbackFcn(app, @rButtonValueChanged, true);
            app.rButton.Text = 'r';
            app.GenSenalesPanel = uipanel(app.UIFigure);
            app.GenSenalesPanel.Title = 'Generador de señales simples';
            app.GenSenalesPanel.SizeChangedFcn = createCallbackFcn(app, @GenSenalesPanelSizeChanged, true);
            app.TipodeondaDropDownLabel = uilabel(app.GenSenalesPanel);
            app.TipodeondaDropDownLabel.Text = 'Tipo de onda';
            app.TipoOnda = uidropdown(app.GenSenalesPanel);
            app.TipoOnda.Items = {'Elegir..', 'Seno', 'Coseno', 'Cuadrada', 'Triangular', 'Sierra'};
            app.TipoOnda.Value = 'Elegir..';
            app.AmplitudLabel = uilabel(app.GenSenalesPanel);
            app.AmplitudLabel.Text = 'Amplitud';
            app.AmplitEditField = uieditfield(app.GenSenalesPanel, 'text');
            app.AmplitEditField.Value = '1';
            app.VLabel = uilabel(app.GenSenalesPanel);
            app.VLabel.Text = 'V';
            app.HzLabel = uilabel(app.GenSenalesPanel);
            app.HzLabel.Text = 'Hz';
            app.FrecEditField = uieditfield(app.GenSenalesPanel, 'text');
            app.FrecEditField.Value = '10';
            app.FrecuenciaLabel = uilabel(app.GenSenalesPanel);
            app.FrecuenciaLabel.Text = 'Frecuencia';
            app.sLabel = uilabel(app.GenSenalesPanel);
            app.sLabel.Text = 's';
            app.DurationEditField = uieditfield(app.GenSenalesPanel, 'text');
            app.DurationEditField.Value = '2';
            app.DuracionLabel = uilabel(app.GenSenalesPanel);
            app.DuracionLabel.Text = 'Duracion';
            app.GenerarButton = uibutton(app.GenSenalesPanel, 'push');
            app.GenerarButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarButtonPushed, true);
            app.GenerarButton.Text = 'Generar';
            app.HzLabel_2 = uilabel(app.GenSenalesPanel);
            app.HzLabel_2.Text = 'Hz';
            app.FrecmuestreoEditField = uieditfield(app.GenSenalesPanel, 'text');
            app.FrecmuestreoEditField.Value = '1000';
            app.FrecmuestreoLabel = uilabel(app.GenSenalesPanel);
            app.FrecmuestreoLabel.Text = 'Frec. muestreo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end